classdef MatlabProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SuperResolutionButton           matlab.ui.control.Button
        TilingtheImageButton            matlab.ui.control.Button
        ImageMorphingButton             matlab.ui.control.Button
        ApplyingdifferentfiltersDropDown  matlab.ui.control.DropDown
        ApplyingdifferentfiltersDropDownLabel  matlab.ui.control.Label
        ImageManipulationDropDown       matlab.ui.control.DropDown
        ImageManipulationDropDownLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        BVRITHyderabadCollegeofEngineeringforWomenIMAGEOPERATIONSUSINGAPPDESIGNERLabel  matlab.ui.control.Label
        ImageSubtractionButton          matlab.ui.control.Button
        AddingdifferentNoisesDropDown   matlab.ui.control.DropDown
        AddingdifferentNoisesDropDownLabel  matlab.ui.control.Label
        DetectEdgesButton               matlab.ui.control.Button
        BrightnessKnob                  matlab.ui.control.Knob
        BrightnessKnobLabel             matlab.ui.control.Label
        ChangingColourofimagesButton    matlab.ui.control.Button
        CompressionAndExpansionofImagesButton  matlab.ui.control.Button
        MergingimagesButton             matlab.ui.control.Button
        CornerdetectionButton           matlab.ui.control.Button
        ThresholdDetectionButton        matlab.ui.control.Button
        InputImage2Button               matlab.ui.control.Button
        InputImageButton                matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
    img     % webcam image
    img2    % file-loaded image
   h
   compressedImage
   expansion
   e
   g
   k
   img5
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            
             cam = webcam(1);                             % Connect to webcam
            app.img = snapshot(cam);                         % Capture image
            imshow(app.img, 'Parent', app.UIAxes);           % Display in UIAxes
            clear cam  
       

        end

        % Button pushed function: InputImage2Button
        function InputImage2ButtonPushed(app, event)
            [file,path] = uigetfile("*.*");
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.img2 = imread(fullfile(path,file));
            imshow(app.img2,'parent',app.UIAxes2);

            title(app.UIAxes2,"input image2",'Color','r');
        end

        % Button pushed function: ThresholdDetectionButton
        function ThresholdDetectionButtonPushed(app, event)
            image = app.img;

% Convert the image to grayscale
grayImage = rgb2gray(image);

% Perform automatic thresholding
threshold=0.5;

binaryImage =grayImage>threshold*355;

% Display the original and binary images
imshow(binaryImage,'parent',app.UIAxes2);
title(app.UIAxes2,"Threshold Detected ",'Color','r');
        end

        % Button pushed function: CornerdetectionButton
        function CornerdetectionButtonPushed(app, event)
            image= rgb2gray(app.img);

% Find the corners
corners = detectHarrisFeatures(image);

% Extract corner locations
cornerLocations = corners.Location;

% Display the original image with detected corners overlaid
title(app.UIAxes2,"Corner Detection",'Color','r');
imshow(image, 'Parent', app.UIAxes2);
hold(app.UIAxes2, 'on');
plot(app.UIAxes2, cornerLocations(:,1), cornerLocations(:,2), 'r*');
hold(app.UIAxes2, 'off');
        end

        % Button pushed function: MergingimagesButton
        function MergingimagesButtonPushed(app, event)
             a= app.img;
            b=app.img2;
             app.g=size(a);
             b=imresize(b,[app.g(1),app.g(2)]);
          
            app.img5 = cat(2 ,a,b);
            imshow(app.img5,'parent',app.UIAxes3);
           
             title(app.UIAxes3,"Mergedimage",'Color','r');
        end

        % Button pushed function: CompressionAndExpansionofImagesButton
        function CompressionAndExpansionofImagesButtonPushed(app, event)
             app.h = app.img;
             compress= imresize(app.h, 0.5); % Compress the image by reducing the size
            app.compressedImage = compress;
            title(app.UIAxes2,"Compressed Image",'Color','r');
          
            imshow(compress, 'Parent', app.UIAxes2);
            app.e=app.compressedImage;
            
           
            app.expansion= imresize(app.e,2);
            %expandedimg = expansion;
            title(app.UIAxes3,"Expanded Image",'Color','r');
            imshow(app.expansion, 'Parent', app.UIAxes3);
        end

        % Callback function
        function ImageBrighteningButtonPushed(app, event)
             bk = app.BrightnessKnob.Value;

            % Adjust brightness of the image
            adjustedImage = uint8(double(app.img) + bk);
           

            % Display the adjusted image
            imshow(adjustedImage, 'Parent', app.UIAxes2);
            title(app.UIAxes2,"Changing Brightness",'Color','r');

        end

        % Button pushed function: ChangingColourofimagesButton
        function ChangingColourofimagesButtonPushed(app, event)
             inpict = app.img;

 %Apply HSV adjustment
       hsv_adjusted = rgb2hsv(inpict);
       hsv_adjusted(:,:,1) = mod(hsv_adjusted(:,:,1) - 0.08, 1); % Adjust hue
       hsv_adjusted(:,:,2) = min(hsv_adjusted(:,:,2) * 15, 1);   % Adjust saturation
       hsv_adjusted(:,:,3) = min(hsv_adjusted(:,:,3) * 0.8, 1);  % Adjust value
       modpict = hsv2rgb(hsv_adjusted);


% Replace pixels with modified image
       outpict = modpict;
  
% Display the modified image
       imshow(outpict, 'Parent', app.UIAxes2);
       title(app.UIAxes2,"ColorChanged Image",'Color','r');

       title(app.UIAxes2,"ColorChanged Image",'Color','r');
        end

        % Value changed function: BrightnessKnob
        function BrightnessKnobValueChanged(app, event)
            bk = app.BrightnessKnob.Value;

            % Adjust brightness of the image
            adjustedImage = uint8(double(app.img) + bk);
           

            % Display the adjusted image
            imshow(adjustedImage, 'Parent', app.UIAxes2);
            title(app.UIAxes2,"Changing Brightness",'Color','r');

        end

        % Button pushed function: DetectEdgesButton
        function DetectEdgesButtonPushed(app, event)
             grayImage = rgb2gray(app.img);
          
        % Perform edge detection using Canny method
              edgeCanny = edge(grayImage, 'Canny');

            % Display the edge-detected images
            montage({ edgeCanny}, 'Parent', app.UIAxes2);
            title(app.UIAxes2, { 'Edges Detected (Canny)'},'Color','r');
        
        end

        % Value changed function: AddingdifferentNoisesDropDown
        function AddingdifferentNoisesDropDownValueChanged(app, event)
            %value = app.AddingdifferentNoisesDropDown.Value;
            noisetype = app.AddingdifferentNoisesDropDown.Value;
            
            % Perform action based on selected value
            switch noisetype
                
                case 'Poisson'
                    noisyImage = imnoise(app.img,'poisson');
                    %noisyImage = rgb2gray(noisyImage);
                    %app.CleanedImage = wiener2(noisyImage, [2 2]);
                    % Add your logic here
                case 'Salt & Pepper'
                    noisyImage = imnoise(app.img, 'salt & pepper', 0.05);
                    
                    %noisyImage = rgb2gray(noisyImage);
                   % app.CleanedImage = medfilt2(noisyImage, [3 3]);
                    % Add your logic here
                case 'Speckle'
                      noisyImage = imnoise(app.img, 'speckle', 0.04);
                     % noisyImage = rgb2gray(noisyImage);
                     % app.CleanedImage = wiener2(noisyImage, [5 5]);
                case 'Gaussian'
                    noisyImage= imnoise(app.img, 'gaussian', 0, 0.01);
                   % noisyImage = rgb2gray(noisyImage);
                    %app.CleanedImage = imgaussfilt(noisyImage, 2);
                otherwise
                    % Handle other cases or no action needed
                    %disp('Unknown option selected');
            end
            title(app.UIAxes2,"Noisy Image",'Color','r');
             % title(app.UIAxes_4,"DeNoisy Image");
          
            imshow(noisyImage, 'Parent', app.UIAxes2);
            %imshow(app.CleanedImage, 'Parent', app.UIAxes_4);
        
        end

        % Button pushed function: ImageSubtractionButton
        function ImageSubtractionButtonPushed(app, event)
            x=app.img;
          y=app.img2;
          app.g=size(x);
          y=imresize(y,[app.g(1),app.g(2)]);
          
          imshow(x-y,'Parent',app.UIAxes3);
          title(app.UIAxes3,"Subtracted Image",'Color','r');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes2)
            app.UIAxes2.Title.String='';
            cla(app.UIAxes3)
            app.UIAxes3.Title.String='';
        end

        % Value changed function: ImageManipulationDropDown
        function ImageManipulationDropDownValueChanged(app, event)
            value = app.ImageManipulationDropDown.Value;
         switch value
            case 'Grayscale'
                s = rgb2gray(app.img);
                % Display the grayscale image
               

            case 'Dilation'
                % Create a structuring element
                se = strel('disk', 5);
                % Perform dilation
                app.k = imbinarize(rgb2gray(app.img));
                s = imdilate(app.k, se);
                % Display the dilated image
               
            case 'Erosion'
                % Create a structuring element
                se = strel('disk', 5);
                % Perform erosion
                app.k = imbinarize(rgb2gray(app.img));
                s = imerode(app.k, se);
                % Display the eroded image
              
            case 'HSV Conversion'
                % Convert the image to HSV
                s = rgb2hsv(app.img);
                % Display the HSV image
              

            otherwise
                % Handle unexpected cases here
        end
          imshow(s, 'Parent', app.UIAxes2);
          

        end

        % Value changed function: ApplyingdifferentfiltersDropDown
        function ApplyingdifferentfiltersDropDownValueChanged(app, event)
             value = app.ApplyingdifferentfiltersDropDown.Value;
             filterOption= app.ApplyingdifferentfiltersDropDown.Value;
           
             switch filterOption
                 case 'Gaussian '
            % Example: Applying Gaussian filter
             app.k=rgb2gray(app.img);
            filteredImage = imgaussfilt(app.k, 2);  % Adjust sigma as needed
        case 'Median'
            % Example: Applying Median filter
            app.k=rgb2gray(app.img);
            filteredImage = medfilt2(app.k, [3 3]);  % Adjust filter size as needed
        case 'Wiener'
            % Example: Applying Wiener filter
             app.k=rgb2gray(app.img);
            filteredImage = wiener2(app.k, [5 5]);  % Adjust filter size as needed
        case 'Mean'
            % Example: Applying Mean filter
             app.k=rgb2gray(app.img);
            filteredImage = imfilter(app.k, fspecial('average', [3 3]));  % Adjust filter size as needed
        otherwise
            disp('Unknown filter option selected.');
            return;
             end
              imshow(filteredImage, 'Parent', app.UIAxes2);
              title(app.UIAxes2,"Filtered Image",'Color','r');
        end

        % Button pushed function: ImageMorphingButton
        function ImageMorphingButtonPushed(app, event)
            img1 =app.img;
            app.img2 = app.img2;

% Resize the second image to match the size of the first image
            app.img2 = imresize(app.img2, [size(img1, 1), size(img1, 2)]);

% Check if the images have the same number of color channels
            if size(img1, 3) ~= size(app.img2, 3)
                  error('The two images have different numbers of color channels');
            end

% Define the morphing parameter
            alpha = 0.5; % Change this value to set the morphing stage (0 to 1)

% Compute the intermediate morphed image
            morphedImg = (1 - alpha) * double(img1) + alpha * double(app.img2);

% Convert to uint8
            morphedImg = uint8(morphedImg);

% Display the morphed image
            imshow(morphedImg,'Parent',app.UIAxes3);
            title(app.UIAxes3,"Morphed Image",'Color','r');
        end

        % Button pushed function: TilingtheImageButton
        function TilingtheImageButtonPushed(app, event)
             app.img = app.img;

% Create a larger canvas to tile the image
      [~, ~, ~] = size(app.img);
      tiledImg = repmat(app.img, 3,3);  % Tiling the image 2x2
     imshow(tiledImg,'Parent',app.UIAxes2)
     title(app.UIAxes2,"Image Tiling ",'Color','r');
        end

        % Button pushed function: SuperResolutionButton
        function SuperResolutionButtonPushed(app, event)
             imgLR=app.img;
            scalingFactor = 2;  % Adjust this scaling factor as needed
            imgHR = imresize(imgLR, scalingFactor, 'bicubic');

            % Display the high-resolution image
            imshow(imgHR,'Parent',app.UIAxes2);
             title(app.UIAxes2,"Super Resolved Image",'Color','r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.InputImageButton = uibutton(app.UIFigure, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'InputImage';
            app.InputImage2Button = uibutton(app.UIFigure, 'push');
            app.InputImage2Button.ButtonPushedFcn = createCallbackFcn(app, @InputImage2ButtonPushed, true);
            app.InputImage2Button.Text = 'InputImage2';
            app.ThresholdDetectionButton = uibutton(app.UIFigure, 'push');
            app.ThresholdDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdDetectionButtonPushed, true);
            app.ThresholdDetectionButton.Text = 'ThresholdDetection';
            app.CornerdetectionButton = uibutton(app.UIFigure, 'push');
            app.CornerdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CornerdetectionButtonPushed, true);
            app.CornerdetectionButton.Text = 'Cornerdetection';
            app.MergingimagesButton = uibutton(app.UIFigure, 'push');
            app.MergingimagesButton.ButtonPushedFcn = createCallbackFcn(app, @MergingimagesButtonPushed, true);
            app.MergingimagesButton.Text = 'Mergingimages';
            app.CompressionAndExpansionofImagesButton = uibutton(app.UIFigure, 'push');
            app.CompressionAndExpansionofImagesButton.ButtonPushedFcn = createCallbackFcn(app, @CompressionAndExpansionofImagesButtonPushed, true);
            app.CompressionAndExpansionofImagesButton.Text = 'CompressionAndExpansionofImages';
            app.ChangingColourofimagesButton = uibutton(app.UIFigure, 'push');
            app.ChangingColourofimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangingColourofimagesButtonPushed, true);
            app.ChangingColourofimagesButton.Text = 'ChangingColourofimages';
            app.BrightnessKnobLabel = uilabel(app.UIFigure);
            app.BrightnessKnobLabel.Text = 'BrightnessKnob';
            app.BrightnessKnob = uiknob(app.UIFigure, 'continuous');
            app.BrightnessKnob.ValueChangedFcn = createCallbackFcn(app, @BrightnessKnobValueChanged, true);
            app.DetectEdgesButton = uibutton(app.UIFigure, 'push');
            app.DetectEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectEdgesButtonPushed, true);
            app.DetectEdgesButton.Text = 'DetectEdges';
            app.AddingdifferentNoisesDropDownLabel = uilabel(app.UIFigure);
            app.AddingdifferentNoisesDropDownLabel.Text = 'AddingdifferentNoises';
            app.AddingdifferentNoisesDropDown = uidropdown(app.UIFigure);
            app.AddingdifferentNoisesDropDown.Items = {'', 'Poisson', 'Salt & Pepper', 'Speckle', 'Gaussian'};
            app.AddingdifferentNoisesDropDown.ValueChangedFcn = createCallbackFcn(app, @AddingdifferentNoisesDropDownValueChanged, true);
            app.AddingdifferentNoisesDropDown.Value = 'Poisson';
            app.ImageSubtractionButton = uibutton(app.UIFigure, 'push');
            app.ImageSubtractionButton.ButtonPushedFcn = createCallbackFcn(app, @ImageSubtractionButtonPushed, true);
            app.ImageSubtractionButton.Text = 'ImageSubtraction';
            app.BVRITHyderabadCollegeofEngineeringforWomenIMAGEOPERATIONSUSINGAPPDESIGNERLabel = uilabel(app.UIFigure);
            app.BVRITHyderabadCollegeofEngineeringforWomenIMAGEOPERATIONSUSINGAPPDESIGNERLabel.Text = {'                                     BVRIT Hyderabad College of Engineering for Women '; ''; '                                          IMAGE OPERATIONS USING APP DESIGNER '};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LOGO1.jpg');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ImageManipulationDropDownLabel = uilabel(app.UIFigure);
            app.ImageManipulationDropDownLabel.Text = 'ImageManipulation';
            app.ImageManipulationDropDown = uidropdown(app.UIFigure);
            app.ImageManipulationDropDown.Items = {'', 'Grayscale', 'Dilation', 'Erosion', 'HSV Conversion'};
            app.ImageManipulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageManipulationDropDownValueChanged, true);
            app.ImageManipulationDropDown.Value = '';
            app.ApplyingdifferentfiltersDropDownLabel = uilabel(app.UIFigure);
            app.ApplyingdifferentfiltersDropDownLabel.Text = 'Applyingdifferentfilters';
            app.ApplyingdifferentfiltersDropDown = uidropdown(app.UIFigure);
            app.ApplyingdifferentfiltersDropDown.Items = {'', 'Gaussian', 'Median', 'Wiener', 'Mean'};
            app.ApplyingdifferentfiltersDropDown.ValueChangedFcn = createCallbackFcn(app, @ApplyingdifferentfiltersDropDownValueChanged, true);
            app.ApplyingdifferentfiltersDropDown.Value = 'Gaussian';
            app.ImageMorphingButton = uibutton(app.UIFigure, 'push');
            app.ImageMorphingButton.ButtonPushedFcn = createCallbackFcn(app, @ImageMorphingButtonPushed, true);
            app.ImageMorphingButton.Text = 'Image Morphing';
            app.TilingtheImageButton = uibutton(app.UIFigure, 'push');
            app.TilingtheImageButton.ButtonPushedFcn = createCallbackFcn(app, @TilingtheImageButtonPushed, true);
            app.TilingtheImageButton.Text = 'TilingtheImage';
            app.SuperResolutionButton = uibutton(app.UIFigure, 'push');
            app.SuperResolutionButton.ButtonPushedFcn = createCallbackFcn(app, @SuperResolutionButtonPushed, true);
            app.SuperResolutionButton.Text = 'SuperResolution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end